classdef Act3App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        x23Button                   matlab.ui.control.Button
        cosxx3Button                matlab.ui.control.Button
        x3x16Button                 matlab.ui.control.Button
        x42x1Button                 matlab.ui.control.Button
        MtododeNewtonRaphsonLabel   matlab.ui.control.Label
        RazconfzeroEditField        matlab.ui.control.NumericEditField
        RazconfuncinfzeroLabel      matlab.ui.control.Label
        RazconfzeroEditField_2      matlab.ui.control.NumericEditField
        RazconNewtonRaphsonLabel    matlab.ui.control.Label
        RazconfzeroEditField_3      matlab.ui.control.NumericEditField
        IteracionesLabel            matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        LimpiarGrficasyDatosButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: x23Button
        function x23ButtonPushed(app, event)
            % Ejercicio 1
            clc;
            function [xi,Iteraciones] = NewtonR(f,fdx,xi,error)
                Iteraciones = 0
                    while abs(subs(f,xi)) > error
                        xi = xi - subs(f,xi)/subs(fdx,xi)
                        Iteraciones = Iteraciones + 1
                    end
                    r = subs(f,xi)
                    xi = double(xi)
                    app.RazconfzeroEditField_2.Value = xi
                    app.RazconfzeroEditField_3.Value = Iteraciones
            end
            
            function ejercicio1()
                syms f(x)
                f(x) = (-x.^2)/10 + 3
                fdx = diff(f(x))
                
                [xi,Iteraciones] = NewtonR(f,fdx,5.5,0.001)
                        end
                
                x = [0 15]
                f =@(x) (-x.^2)/10 +3
                
                y1 = @(x) 30/x
                y2 = @(x) x
                
                %subplot(app.UIAxes,2,1,1)
                fplot(app.UIAxes,y1,[-15,15])
                hold (app.UIAxes,"on")
                fplot(app.UIAxes,y2,[-15,15])
                title(app.UIAxes,'Puntos Fijos')
                xlabel(app.UIAxes,'Ejes de las x')
                ylabel(app.UIAxes,'Ejes de las y')
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                
                %subplot(app.UIAxes,2,1,2)
                fplot(app.UIAxes2,y1,[-15,15])
                hold (app.UIAxes2,"on")
                %fplot(f2)
                title(app.UIAxes2,'Grafica Original')
                xlabel(app.UIAxes2,'Ejes de las x')
                ylabel(app.UIAxes2,'Ejes de las y')
                hline = refline(app.UIAxes2,0,0)
                hline.Color = 'r'
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
                
                root = fzero(f,x)
                app.RazconfzeroEditField.Value = root
                
                ejercicio1()        
        end

        % Button pushed function: cosxx3Button
        function cosxx3ButtonPushed(app, event)
            % Ejercicio 2
            clc;
            function [xi,Iteraciones] = NewtonR(f,fdx,xi,error)
                Iteraciones = 0
                    while abs(subs(f,xi)) > error
                        xi = xi - subs(f,xi)/subs(fdx,xi)
                        Iteraciones = Iteraciones + 1
                    end
                    r = subs(f,xi)
                    xi = double(xi)
                    app.RazconfzeroEditField_2.Value = xi
                    app.RazconfzeroEditField_3.Value = Iteraciones
            end
            
            function ejercicio2()
                syms f(x)
                f(x) = cos(x) - x^3
                fdx = diff(f(x))
                
                [xi,Iteraciones] = NewtonR(f,fdx,0.863921,0.001)
            end
                
                x = [0 5]
                f =@(x) cos(x) - x.^3
                
                y1 = @(x) (cos(x)).^(1/3)
                y2 = @(x) x
            
                
                %subplot(app.UIAxes,2,1,1)
                fplot(app.UIAxes,y1,[-3,4])
                hold (app.UIAxes,"on")
                fplot(app.UIAxes,y2,[-3,4])
                title(app.UIAxes,'Puntos Fijos')
                xlabel(app.UIAxes,'Ejes de las x')
                ylabel(app.UIAxes,'Ejes de las y')
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                
                %subplot(app.UIAxes,2,1,2)
                fplot(app.UIAxes2,y1,[-3,4])
                hold (app.UIAxes2,"on")
                %fplot(f2)
                title(app.UIAxes2,'Grafica Original')
                xlabel(app.UIAxes2,'Ejes de las x')
                ylabel(app.UIAxes2,'Ejes de las y')
                hline = refline(app.UIAxes2,0,0)
                hline.Color = 'r'
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
                
                root = fzero(f,x)
                app.RazconfzeroEditField.Value = root
                
                ejercicio2()        
        end

        % Button pushed function: x3x16Button
        function x3x16ButtonPushed(app, event)
            % Ejercicio 3
            clc;
            function [xi,Iteraciones] = NewtonR(f,fdx,xi,error)
                Iteraciones = 0
                    while abs(subs(f,xi)) > error
                        xi = xi - subs(f,xi)/subs(fdx,xi)
                        Iteraciones = Iteraciones + 1
                    end
                    r = subs(f,xi)
                    xi = double(xi)
                    app.RazconfzeroEditField_2.Value = xi
                    app.RazconfzeroEditField_3.Value = Iteraciones
            end
            
            function ejercicio3()
                syms f(x)
                f(x) = x^3 + x + 16
                fdx = diff(f(x))
                
                [xi,Iteraciones] = NewtonR(f,fdx,-2.5198,0.001)
            end
                
                x = [-6 6]
                f =@(x) x.^3 + x + 16
                
                y1 = @(x) -x.^3 - 16
                y2 = @(x) x
            
                
                %subplot(app.UIAxes,2,1,1)
                fplot(app.UIAxes,y1,[-6,6])
                hold (app.UIAxes,"on")
                fplot(app.UIAxes,y2,[-6,6])
                title(app.UIAxes,'Puntos Fijos')
                xlabel(app.UIAxes,'Ejes de las x')
                ylabel(app.UIAxes,'Ejes de las y')
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                
                %subplot(app.UIAxes,2,1,2)
                fplot(app.UIAxes2,y1,[-6,6])
                hold (app.UIAxes2,"on")
                %fplot(f2)
                title(app.UIAxes2,'Grafica Original')
                xlabel(app.UIAxes2,'Ejes de las x')
                ylabel(app.UIAxes2,'Ejes de las y')
                hline = refline(app.UIAxes2,0,0)
                hline.Color = 'r'
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
                
                root = fzero(f,x)
                app.RazconfzeroEditField.Value = root
                
                ejercicio3()        
        end

        % Button pushed function: x42x1Button
        function x42x1ButtonPushed(app, event)
            % Ejercicio 4
            clc;
            function [xi,Iteraciones] = NewtonR(f,fdx,xi,error)
                Iteraciones = 0
                    while abs(subs(f,xi)) > error
                        xi = xi - subs(f,xi)/subs(fdx,xi)
                        Iteraciones = Iteraciones + 1
                    end
                    r = subs(f,xi)
                    xi = double(xi)
                    app.RazconfzeroEditField_2.Value = xi
                    app.RazconfzeroEditField_3.Value = Iteraciones
            end
            
            function ejercicio4()
                syms f(x)
                f(x) = x^4 + 2*x - 1
                fdx = diff(f(x))
                
                [xi,Iteraciones] = NewtonR(f,fdx,1,0.001)
            end
                
                x = [0 2]
                f =@(x) x.^4 + 2.*x - 1
                
                y1 = @(x) (-x.^4 + 1)/2
                y2 = @(x) x


                %subplot(app.UIAxes,2,1,1)
                fplot(app.UIAxes,y1,[-2,4])
                hold (app.UIAxes,"on")
                fplot(app.UIAxes,y2,[-2,4])
                title(app.UIAxes,'Puntos Fijos')
                xlabel(app.UIAxes,'Ejes de las x')
                ylabel(app.UIAxes,'Ejes de las y')
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                
                %subplot(app.UIAxes,2,1,2)
                fplot(app.UIAxes2,y1,[-2,4])
                hold (app.UIAxes2,"on")
                %fplot(f2)
                title(app.UIAxes2,'Grafica Original')
                xlabel(app.UIAxes2,'Ejes de las x')
                ylabel(app.UIAxes2,'Ejes de las y')
                hline = refline(app.UIAxes2,0,0)
                hline.Color = 'r'
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
                
                root = fzero(f,x)
                app.RazconfzeroEditField.Value = root
                
                ejercicio4()     
        end

        % Button pushed function: LimpiarGrficasyDatosButton
        function LimpiarGrficasyDatosButtonPushed(app, event)
           cla(app.UIAxes);
           cla(app.UIAxes2);
           app.RazconfzeroEditField.Value = 0
           app.RazconfzeroEditField_2.Value = 0
           app.RazconfzeroEditField_3.Value = 0
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.x23Button = uibutton(app.UIFigure, 'push');
            app.x23Button.ButtonPushedFcn = createCallbackFcn(app, @x23ButtonPushed, true);
            app.x23Button.Text = '-1/10 x^2+3';
            app.cosxx3Button = uibutton(app.UIFigure, 'push');
            app.cosxx3Button.ButtonPushedFcn = createCallbackFcn(app, @cosxx3ButtonPushed, true);
            app.cosxx3Button.Text = 'cos(x)-x^3';
            app.x3x16Button = uibutton(app.UIFigure, 'push');
            app.x3x16Button.ButtonPushedFcn = createCallbackFcn(app, @x3x16ButtonPushed, true);
            app.x3x16Button.Text = 'x^3+x+16';
            app.x42x1Button = uibutton(app.UIFigure, 'push');
            app.x42x1Button.ButtonPushedFcn = createCallbackFcn(app, @x42x1ButtonPushed, true);
            app.x42x1Button.Text = 'x^4+2x-1';
            app.MtododeNewtonRaphsonLabel = uilabel(app.UIFigure);
            app.MtododeNewtonRaphsonLabel.Text = 'Método de Newton-Raphson';
            app.RazconfzeroEditField = uieditfield(app.UIFigure, 'numeric');
            app.RazconfuncinfzeroLabel = uilabel(app.UIFigure);
            app.RazconfuncinfzeroLabel.Text = 'Raíz con función fzero';
            app.RazconfzeroEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RazconNewtonRaphsonLabel = uilabel(app.UIFigure);
            app.RazconNewtonRaphsonLabel.Text = 'Raíz con Newton-Raphson';
            app.RazconfzeroEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesLabel = uilabel(app.UIFigure);
            app.IteracionesLabel.Text = 'Iteraciones';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Puntos Fijos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Tabla Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LimpiarGrficasyDatosButton = uibutton(app.UIFigure, 'push');
            app.LimpiarGrficasyDatosButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarGrficasyDatosButtonPushed, true);
            app.LimpiarGrficasyDatosButton.Text = 'Limpiar Gráficas y Datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Act3App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end