classdef RuffiniApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        CalcularRacesButton    matlab.ui.control.Button
        GrficasButtonGroup     matlab.ui.container.ButtonGroup
        Grfica1Button          matlab.ui.control.RadioButton
        Grfica2Button          matlab.ui.control.RadioButton
        Grfica3Button          matlab.ui.control.RadioButton
        Grfica4Button          matlab.ui.control.RadioButton
        MtododeRuffiniLabel    matlab.ui.control.Label
        VectordePolinomioEditField_2Label  matlab.ui.control.Label
        VectordePolinomio      matlab.ui.control.EditField
        RacesEditField_2Label  matlab.ui.control.Label
        Raices                 matlab.ui.control.EditField
        GraficarButton         matlab.ui.control.Button
        LimpiarButton          matlab.ui.control.Button
        ColocarelVectordelaforma12345Label  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularRacesButton
        function CalcularRacesButtonPushed(app, event)
            A = textscan(app.VectordePolinomio.Value,'%f','Delimiter',' ')
            A = A{1}
            r = RaicesP(A)
            app.Raices.Value = num2str(r)
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
        if (app.Grfica1Button.Value)
            %% Ejercicio 1
                f = @(x) x.^3 - 6. * x.^2 + 11. * x - 6
                
                %figure(app.UIAxes,"Name","Ejemplo 1")
                fplot(app.UIAxes,f)
                hold (app.UIAxes,"on")
                B = [1 -6 11 -6]
                sol = RaicesP(B)
                plot(app.UIAxes,sol,0,'pk') %or
                title(app.UIAxes,"Ecuacion 1 Ejemplo")
                xlabel(app.UIAxes,"Eje de las x")
                ylabel(app.UIAxes,"Eje de las y")
                xlim (app.UIAxes,[0 4])
                ylim(app.UIAxes,[-5 5])
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'    
                app.UIAxes.XGrid = "on"
                app.UIAxes.YGrid = "on"
        elseif (app.Grfica2Button.Value)
            %% Ejercicio 2
            f = @(x) x.^3 - x.^2 - 4. * x + 4
                
                %figure(app.UIAxes,"Name","Ejemplo 2")
                fplot(app.UIAxes,f)
                hold (app.UIAxes,"on")
                B = [1 -1 -4 4]
                sol = RaicesP(B)
                plot(app.UIAxes,sol,0,'pk') %or
                title(app.UIAxes,"Ecuacion 2 Ejemplo")
                xlabel(app.UIAxes,"Eje de las x")
                ylabel(app.UIAxes,"Eje de las y")
                xlim (app.UIAxes,[-3 3])
                ylim(app.UIAxes,[-20 20])
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'    
                app.UIAxes.XGrid = "on"
                app.UIAxes.YGrid = "on"
        elseif (app.Grfica3Button.Value)
            %% Ejercicio 3
            f = @(x) -x.^4 + 10.*x.^2 - 9
                
                %figure(app.UIAxes,"Name","Ejemplo 3")
                fplot(app.UIAxes,f)
                hold (app.UIAxes,"on")
                B = [-1 0 10 0 -9]
                sol = RaicesP(B)
                plot(app.UIAxes,sol,0,'pk') %or
                title(app.UIAxes,"Ecuacion 3 Ejemplo")
                xlabel(app.UIAxes,"Eje de las x")
                ylabel(app.UIAxes,"Eje de las y")
                xlim (app.UIAxes,[-4 4])
                ylim(app.UIAxes,[-20 20])
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'    
                app.UIAxes.XGrid = "on"
                app.UIAxes.YGrid = "on"
        elseif (app.Grfica4Button.Value)
            %% Ejercicio 4
            f = @(x) 2.*x.^3 + x.^2 - 13. * x + 6
                
                %figure(app.UIAxes,"Name","Ejemplo 4")
                fplot(app.UIAxes,f)
                hold (app.UIAxes,"on")
                B = [2 1 -13 6]
                sol = RaicesP(B)
                plot(app.UIAxes,sol,0,'pk') %or
                title(app.UIAxes,"Ecuacion 4 Ejemplo")
                xlabel(app.UIAxes,"Eje de las x")
                ylabel(app.UIAxes,"Eje de las y")
                xlim (app.UIAxes,[-3.5 3])
                ylim(app.UIAxes,[-10 30])
                hline = refline(app.UIAxes,0,0)
                hline.Color = 'r'    
                app.UIAxes.XGrid = "on"
                app.UIAxes.YGrid = "on"
        end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes);
            app.VectordePolinomio.Value = ' ';
            app.Raices.Value = ' '
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CalcularRacesButton = uibutton(app.UIFigure, 'push');
            app.CalcularRacesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRacesButtonPushed, true);
            app.CalcularRacesButton.Text = 'Calcular Raíces';
            app.GrficasButtonGroup = uibuttongroup(app.UIFigure);
            app.GrficasButtonGroup.Title = 'Gráficas';
            app.Grfica1Button = uiradiobutton(app.GrficasButtonGroup);
            app.Grfica1Button.Text = 'Gráfica 1';
            app.Grfica1Button.Value = true;
            app.Grfica2Button = uiradiobutton(app.GrficasButtonGroup);
            app.Grfica2Button.Text = 'Gráfica 2';
            app.Grfica3Button = uiradiobutton(app.GrficasButtonGroup);
            app.Grfica3Button.Text = 'Gráfica 3';
            app.Grfica4Button = uiradiobutton(app.GrficasButtonGroup);
            app.Grfica4Button.Text = 'Gráfica 4';
            app.MtododeRuffiniLabel = uilabel(app.UIFigure);
            app.MtododeRuffiniLabel.Text = 'Método de Ruffini';
            app.VectordePolinomioEditField_2Label = uilabel(app.UIFigure);
            app.VectordePolinomioEditField_2Label.Text = 'Vector de Polinomio';
            app.VectordePolinomio = uieditfield(app.UIFigure, 'text');
            app.RacesEditField_2Label = uilabel(app.UIFigure);
            app.RacesEditField_2Label.Text = 'Raíces';
            app.Raices = uieditfield(app.UIFigure, 'text');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.ColocarelVectordelaforma12345Label = uilabel(app.UIFigure);
            app.ColocarelVectordelaforma12345Label.Text = 'Colocar el Vector de la forma: 1 2 3 4 5';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Método de Ruffini')
            xlabel(app.UIAxes, {'x'; ''})
            ylabel(app.UIAxes, {'y'; ''})
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RuffiniApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end