classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GraficarCampoElctricoButton  matlab.ui.control.Button
        Q1EditFieldLabel             matlab.ui.control.Label
        Q1EditField                  matlab.ui.control.NumericEditField
        Q2EditFieldLabel             matlab.ui.control.Label
        Q2EditField                  matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarCampoElctricoButton
        function GraficarCampoElctricoButtonPushed(app, event)
            %Inicializamos Variables y Constantes
    ke = 8.9876 * 10 ^9;
    Q1 = app.Q1EditField.Value; % Input
    Q2 = app.Q2EditField.Value; % Input
    
    Q1_d = 1; % Diametro
    Q2_d = 1; % Diametro
    
    Q1x = 0; % Posicion Input
    Q1y = 0; % Posicion Input
    Q2x = 8; % Posicion Input
    Q2y = 0; % Posicion Input
    
    %R = Q2x - Q1x;
    
    %figure("name","Quiver")
    x = linspace(-5,15,20);
    y = linspace(-5,15,20);
    %[x,y] = meshgrid(-5:1:15, -5:1:15);
    [x,y] = meshgrid(x, y);
    
    r1x = x - Q1x; % elemento î del vector de Q1 hasta el punto
    r1y = y - Q1y; % elemento j del vector de Q1 hasta el punto
    r2x = x - Q2x; % elemento î del vector de Q2 hasta el punto
    r2y = y - Q2y; % elemento j del vector de Q2 hasta el punto
    
    Ex = ke .* ( ( (Q1 .* r1x) ./ sqrt(r1x.^2 + r1y.^2).^2 ) + ( (Q2 .* r2x) ./ sqrt(r2x.^2 + r2y.^2).^2 ) );
    Ey = ke .* ( ( (Q1 .* r1y) ./ sqrt(r1x.^2 + r1y.^2).^2 ) + ( (Q2 .* r2y) ./ sqrt(r2x.^2 + r2y.^2).^2 ) );
    
    E = sqrt( Ex.^2 + Ey.^2 );
    
    u = Ex./E;
    v = Ey./E;
    
    axis equal
    quiver(app.UIAxes,x,y,u,v)%,'AutoScaleFactor',0.8)
    hold (app.UIAxes,"on")
    app.UIAxes.XGrid = "on";
    app.UIAxes.YGrid = "on";
    title(app.UIAxes,"Gráfica de Quiver")
    xlabel(app.UIAxes,"Eje x")
    ylabel(app.UIAxes,"Eje y")
    
    rectangle(app.UIAxes,'Position',[(Q1x-(Q1_d/2)) (Q1y-(Q1_d/2)) Q1_d Q1_d],'Curvature',[1,1],'FaceColor',[1 0 0],'EdgeColor',[1 0 0]);
    rectangle(app.UIAxes,'Position',[(Q2x-(Q2_d/2)) (Q2y-(Q2_d/2)) Q2_d Q2_d],'Curvature',[1,1],'FaceColor',[0 0 1],'EdgeColor',[0 0 1]);
    text(app.UIAxes,(Q2x-(Q2_d/2)),(Q2y-(Q2_d/2)),'-','Color','white','FontSize', 30);
    axis(app.UIAxes,[-5 10 -2 10]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraficarCampoElctricoButton = uibutton(app.UIFigure, 'push');
            app.GraficarCampoElctricoButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarCampoElctricoButtonPushed, true);
            app.GraficarCampoElctricoButton.Text = 'Graficar Campo Eléctrico';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.Value = 10;
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.Value = -10;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campo Eléctrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end